classdef Madini < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MadiniElementstoMineralInversionUIFigure  matlab.ui.Figure
        DataLoadingPanel              matlab.ui.container.Panel
        LoadElementsButton            matlab.ui.control.Button
        LoadMineralsButton            matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        ElementsConstantsLabel        matlab.ui.control.Label
        MineralsConstantsLabel        matlab.ui.control.Label
        ElementalDataLabel            matlab.ui.control.Label
        DataFileLabel                 matlab.ui.control.Label
        ElementsFileLabel             matlab.ui.control.Label
        MineralsFileLabel             matlab.ui.control.Label
        MineralSelectionPanel         matlab.ui.container.Panel
        MineralsListBox               matlab.ui.control.ListBox
        SelectedMineralsListBox       matlab.ui.control.ListBox
        AvailableMineralsLabel        matlab.ui.control.Label
        SelectedMineralsLabel         matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        UnSelectButton                matlab.ui.control.Button
        ElementSelectionPanel         matlab.ui.container.Panel
        ElementsListBox               matlab.ui.control.ListBox
        SelectedElementsListBox       matlab.ui.control.ListBox
        ElSelectButton                matlab.ui.control.Button
        ElUnSelectButton              matlab.ui.control.Button
        AvailableElementsLabel        matlab.ui.control.Label
        SelectedElementsLabel         matlab.ui.control.Label
        MineralogyEstimationPanel     matlab.ui.container.Panel
        InvertButton                  matlab.ui.control.Button
        ExportResultsButton           matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ReferencesTab                 matlab.ui.container.Tab
        IfthesoftwareisusedpleasereferenceLabel  matlab.ui.control.Label
        IfthesoftwareisusedpleasereferenceLabel_2  matlab.ui.control.Label
        InstructionsTab               matlab.ui.container.Tab
        LoadcustomizedelementaldataifdesiredLabel  matlab.ui.control.Label
        AboutTab                      matlab.ui.container.Tab
        MustafaAAlIbrahimMustafaGeoscientistoutlookcomLabel_2  matlab.ui.control.Label
        Version101Copyright2018Label  matlab.ui.control.Label
        ContactInformationLabel_2     matlab.ui.control.Label
    end


    properties (Access = public)
        Elements % Elements
        Minerals % Minerals
        MineralsAll
        Data     % Data
        Results  % Results
        Input    % Input
    end

    methods (Access = private)
    
        function [] = updateElements(app)
            availableElements = app.Data.Properties.VariableNames(2:end);
            selectedMinerals = app.SelectedMineralsListBox.Items;
            selectedMinerals = app.Minerals(ismember(app.Minerals.Minerals, selectedMinerals),:);
            [~, possibleElements] = getPossibleMinerals(selectedMinerals, availableElements);
            app.SelectedElementsListBox.Items = possibleElements;
            app.ElementsListBox.Items ={};   
        end
        
        function [] = resetApp(app)
             app.SelectedMineralsListBox.Items ={};
             app.ElementsListBox.Items ={};
             app.SelectedElementsListBox.Items ={};
             app.MineralsListBox.Items={};
             app.Results = [];
             app.Input = []; 
        end
        
    end

    methods (Access = public)
    
        
        function [] = analyzeInputFiles(app)
             if ~isempty(app.Elements) && ~isempty(app.MineralsAll) && ~isempty(app.Data)
             resetApp(app);
             availableElements = app.Data.Properties.VariableNames(2:end); 
             app.Minerals = AnalyzeMinerals(app.Elements, app.MineralsAll);
             app.Minerals = getPossibleMinerals(app.Minerals, availableElements);
             app.MineralsListBox.Items = sort(app.Minerals.Minerals);
             end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Elements = []; app.Minerals =[]; app.Data=[];
            
            pathName = GetExecutableFolder();
            
            try
            fileName = 'Elements.csv';
            elementsFileName = fullfile(pathName, fileName);
            [app.Elements] = readtable(elementsFileName, 'ReadRowNames',true);
            app.ElementsFileLabel.Text = fileName;
            
            fileName = 'Minerals.csv';
            mineralsFileName = fullfile(pathName, fileName);
            [app.MineralsAll] = readtable(mineralsFileName, 'ReadRowNames',true);
            app.MineralsFileLabel.Text = fileName;
            catch exception
                
            end 
                
            cd(pathName);
            resetApp(app);
        end

        % Button pushed function: ElSelectButton
        function ElSelectButtonPushed(app, event)
            selectedItemsLeft = app.ElementsListBox.Value;
            allItemesLeft = app.ElementsListBox.Items;          
            allItemesRight = app.SelectedElementsListBox.Items;
            app.SelectedElementsListBox.Items = [allItemesRight, selectedItemsLeft];
            app.ElementsListBox.Items = allItemesLeft(~ismember(allItemesLeft, selectedItemsLeft));
            app.Results = [];
            app.Input = []; 
        end

        % Button pushed function: ElUnSelectButton
        function ElUnSelectButtonPushed(app, event)
            
            selectedItemsRight = app.SelectedElementsListBox.Value;
            allItemesRight = app.SelectedElementsListBox.Items;
            
            selectedMineralsNames = app.SelectedMineralsListBox.Items;
            selectedMinerals = selectMineralsByName(app.Minerals, selectedMineralsNames);
            essentialMinerals = checkIfElementIsEssential(selectedMinerals, allItemesRight, selectedItemsRight);
            if isempty(essentialMinerals)==true
                allItemesLeft = app.ElementsListBox.Items;          
                app.ElementsListBox.Items = sort([allItemesLeft, selectedItemsRight]);
                app.SelectedElementsListBox.Items = allItemesRight(~ismember(allItemesRight, selectedItemsRight));
            else
                message = ['Cannot remove ', char(selectedItemsRight), ' as it is essential for ', strjoin(essentialMinerals,', '), '.'];
                uialert(app.MadiniElementstoMineralInversionUIFigure, message,'Cannot Remove Element!');
            end
             app.Results = [];
             app.Input = [];
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            
            if isempty(app.Results) == false
             [fileName,pathName,filterIndex, isSelected] = selectFile('Save Results', {'.csv'}, true)
             if isSelected == true
                 outputFileName = fullfile(pathName, fileName);
                 output = [app.Input, app.Results];
                 writetable(output, outputFileName, 'WriteRowNames',true);
             end
            end
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            mineralToUse = app.SelectedMineralsListBox.Items;
            elementsToUse = app.SelectedElementsListBox.Items;
            if isempty(mineralToUse) == false && isempty(elementsToUse) == false
            [Aprime, AprimeTable] = ConstructAprimeMatrix(app.Minerals, elementsToUse, mineralToUse);
            dataToUse = FilterData(app.Data,elementsToUse);
            [app.Results, app.Input] = InvertToMinerals(Aprime, dataToUse, mineralToUse);
            PlotResultsTable(app.Results, 1, app.Input)
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fileName, pathName, filterIndex, isSelected] = selectFile('Select Data File');
            if isSelected == true
               dataFileName = fullfile(pathName, fileName);
               [app.Data] = readtable(dataFileName, 'ReadRowNames',true);
               app.DataFileLabel.Text = fileName;
               cd(pathName);
               resetApp(app);
               analyzeInputFiles(app);
            end
        end

        % Button pushed function: LoadElementsButton
        function LoadElementsButtonPushed(app, event)
            [fileName,pathName,filterIndex, isSelected] = selectFile('Select Elemental Constants File');
            if isSelected == true
                elementsFileName = fullfile(pathName, fileName);
                [app.Elements] = readtable(elementsFileName, 'ReadRowNames',true);
                app.ElementsFileLabel.Text = fileName;
                cd(pathName);
                resetApp(app);
                analyzeInputFiles(app);
            end
        end

        % Button pushed function: LoadMineralsButton
        function LoadMineralsButtonPushed(app, event)
            [fileName, pathName, filterIndex, isSelected] = selectFile('Select Mineralogical Constants File');
            if isSelected == true
               mineralsFileName = fullfile(pathName, fileName);
               [app.MineralsAll] = readtable(mineralsFileName, 'ReadRowNames',true);
               app.MineralsFileLabel.Text = fileName;
               cd(pathName);
               resetApp(app);
               analyzeInputFiles(app);
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            selectedItemsLeft = app.MineralsListBox.Value;
            allItemesLeft = app.MineralsListBox.Items;          
            allItemesRight = app.SelectedMineralsListBox.Items;
            app.SelectedMineralsListBox.Items = [allItemesRight, selectedItemsLeft];
            app.MineralsListBox.Items = allItemesLeft(~ismember(allItemesLeft, selectedItemsLeft));
            app.ElementsListBox.Items ={};
            app.SelectedElementsListBox.Items ={};
            updateElements(app)
            app.Results = [];
            app.Input = []; 
        end

        % Button pushed function: UnSelectButton
        function UnSelectButtonPushed(app, event)
            selectedItemsRight = app.SelectedMineralsListBox.Value;
            allItemesLeft = app.MineralsListBox.Items;          
            allItemesRight = app.SelectedMineralsListBox.Items;
            app.MineralsListBox.Items = sort([allItemesLeft, selectedItemsRight]);
            app.SelectedMineralsListBox.Items = allItemesRight(~ismember(allItemesRight, selectedItemsRight));
            app.ElementsListBox.Items ={};
            app.SelectedElementsListBox.Items ={}; 
            updateElements(app)
            app.Results = [];
            app.Input = []; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MadiniElementstoMineralInversionUIFigure = uifigure;
            app.MadiniElementstoMineralInversionUIFigure.Name = 'Madini - Elements to Mineral Inversion';
            app.DataLoadingPanel = uipanel(app.MadiniElementstoMineralInversionUIFigure);
            app.DataLoadingPanel.Title = 'Data Loading';
            app.LoadElementsButton = uibutton(app.DataLoadingPanel, 'push');
            app.LoadElementsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadElementsButtonPushed, true);
            app.LoadElementsButton.Text = 'Load Elements';
            app.LoadMineralsButton = uibutton(app.DataLoadingPanel, 'push');
            app.LoadMineralsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMineralsButtonPushed, true);
            app.LoadMineralsButton.Text = 'Load Minerals';
            app.LoadDataButton = uibutton(app.DataLoadingPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ElementsConstantsLabel = uilabel(app.DataLoadingPanel);
            app.ElementsConstantsLabel.Text = 'Elements Constants';
            app.MineralsConstantsLabel = uilabel(app.DataLoadingPanel);
            app.MineralsConstantsLabel.Text = 'Minerals Constants';
            app.ElementalDataLabel = uilabel(app.DataLoadingPanel);
            app.ElementalDataLabel.Text = 'Elemental Data';
            app.DataFileLabel = uilabel(app.DataLoadingPanel);
            app.DataFileLabel.Text = '';
            app.ElementsFileLabel = uilabel(app.DataLoadingPanel);
            app.ElementsFileLabel.Text = '';
            app.MineralsFileLabel = uilabel(app.DataLoadingPanel);
            app.MineralsFileLabel.Text = '';
            app.MineralSelectionPanel = uipanel(app.MadiniElementstoMineralInversionUIFigure);
            app.MineralSelectionPanel.Title = 'Mineral Selection';
            app.MineralsListBox = uilistbox(app.MineralSelectionPanel);
            app.MineralsListBox.Items = {};
            app.MineralsListBox.Multiselect = 'on';
            app.MineralsListBox.Value = {};
            app.SelectedMineralsListBox = uilistbox(app.MineralSelectionPanel);
            app.SelectedMineralsListBox.Items = {};
            app.SelectedMineralsListBox.Multiselect = 'on';
            app.SelectedMineralsListBox.Value = {};
            app.AvailableMineralsLabel = uilabel(app.MineralSelectionPanel);
            app.AvailableMineralsLabel.Text = 'Available Minerals';
            app.SelectedMineralsLabel = uilabel(app.MineralSelectionPanel);
            app.SelectedMineralsLabel.Text = 'Selected Minerals';
            app.SelectButton = uibutton(app.MineralSelectionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = '⇒';
            app.UnSelectButton = uibutton(app.MineralSelectionPanel, 'push');
            app.UnSelectButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectButtonPushed, true);
            app.UnSelectButton.Text = '⇐';
            app.ElementSelectionPanel = uipanel(app.MadiniElementstoMineralInversionUIFigure);
            app.ElementSelectionPanel.Title = 'Element Selection';
            app.ElementsListBox = uilistbox(app.ElementSelectionPanel);
            app.ElementsListBox.Items = {};
            app.ElementsListBox.Value = {};
            app.SelectedElementsListBox = uilistbox(app.ElementSelectionPanel);
            app.SelectedElementsListBox.Items = {};
            app.SelectedElementsListBox.Value = {};
            app.ElSelectButton = uibutton(app.ElementSelectionPanel, 'push');
            app.ElSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ElSelectButtonPushed, true);
            app.ElSelectButton.Text = '⇒';
            app.ElUnSelectButton = uibutton(app.ElementSelectionPanel, 'push');
            app.ElUnSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ElUnSelectButtonPushed, true);
            app.ElUnSelectButton.Text = '⇐';
            app.AvailableElementsLabel = uilabel(app.ElementSelectionPanel);
            app.AvailableElementsLabel.Text = 'Available Elements';
            app.SelectedElementsLabel = uilabel(app.ElementSelectionPanel);
            app.SelectedElementsLabel.Text = 'Selected Elements';
            app.MineralogyEstimationPanel = uipanel(app.MadiniElementstoMineralInversionUIFigure);
            app.MineralogyEstimationPanel.Title = 'Mineralogy Estimation';
            app.InvertButton = uibutton(app.MineralogyEstimationPanel, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.ExportResultsButton = uibutton(app.MineralogyEstimationPanel, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.TabGroup = uitabgroup(app.MadiniElementstoMineralInversionUIFigure);
            app.ReferencesTab = uitab(app.TabGroup);
            app.ReferencesTab.Title = 'References';
            app.IfthesoftwareisusedpleasereferenceLabel = uilabel(app.ReferencesTab);
            app.IfthesoftwareisusedpleasereferenceLabel.Text = 'If the software is used, please reference:';
            app.IfthesoftwareisusedpleasereferenceLabel_2 = uilabel(app.ReferencesTab);
            app.IfthesoftwareisusedpleasereferenceLabel_2.Text = {'Al Ibrahim, M. A., Mukerji, T., 2017, Thermal maturation effects on the'; 'elastic properties of organic-rich mudrocks: Presented at the SEG'; 'Conferance, 24-27 September, Houston, TX, 4 p.'; ''; 'De Caritat, P., Bloch, J., and Hutcheon, I, 1994, LPNORM: A linear '; 'programming normative analysis code: Computer and Geosciences, '; 'v. 20, no. 3, p. 313-347. '};
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.LoadcustomizedelementaldataifdesiredLabel = uilabel(app.InstructionsTab);
            app.LoadcustomizedelementaldataifdesiredLabel.Text = {'1. Load customized elemental data if desired.'; '2. Load customized mineral database if desired.'; '3. Load the elemental input data.'; '4. The software will analyze the mineral database and the elemental'; '    data and gives all the possible minerals that can be calculated.'; '5. Select the expected mineral assemblage. As the minerals are'; '     added to the list, the used elements are updated automatically.'; '6. If desired, remove some of the element that are already selected.'; '    (e.g., if they have low accuracy).'; '7. Press the invert button. Results will be plotted.'; '8. If desired, export the results using the Export Results button.'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.MustafaAAlIbrahimMustafaGeoscientistoutlookcomLabel_2 = uilabel(app.AboutTab);
            app.MustafaAAlIbrahimMustafaGeoscientistoutlookcomLabel_2.Text = {'Mustafa A. Al Ibrahim (Mustafa.Geoscientist@outlook.com)'; ''; 'Allegra Hosford Scheirer (allegras@stanford.edu)'; 'Basin and Petroleum System Modeling Group'; 'Stanford University'; ''; 'Icon and splash screen is modified from "Crystal growth Icon" by '; 'Lorc (http://lorcblog.blogspot.com) distributed under CC BY 3.0.'};
            app.Version101Copyright2018Label = uilabel(app.AboutTab);
            app.Version101Copyright2018Label.Text = 'Version 1.01, Copyright@2018';
            app.ContactInformationLabel_2 = uilabel(app.AboutTab);
            app.ContactInformationLabel_2.Text = 'Contact Information:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Madini

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MadiniElementstoMineralInversionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MadiniElementstoMineralInversionUIFigure)
        end
    end
end